classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VideoEditFieldLabel  matlab.ui.control.Label
        VideoEditField       matlab.ui.control.NumericEditField
        TestButton           matlab.ui.control.Button
        ResultTextAreaLabel  matlab.ui.control.Label
        ResultTextArea       matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            %Give the path of the Feature Extracted file for which the Forging needs to be tested.
Test_File = load('C:\Users\Akaps\Desktop\MAT\example\TESTING_SET\test_2_stego1.mat');

%Store the names of the Frames for reference to its feature vector
Test_File_names = Test_File.names;


emptyCells = cellfun('isempty', Test_File_names); 
Test_File_names(emptyCells) = [];

%Store the Feature values for each frame of the given input
T = Test_File.d(ismember(Test_File.names,Test_File_names),:);

%Create the testing set
testing_set = linspace(1,size(T,1),size(T,1));

TST_Features = T;
%Finally, now call the ensemble_testing function which gives the output as
%whether a particular frame is forged(1) or original(-1).
load('C:\Users\Akaps\Desktop\MAT\trained_ensemble2.mat');
test_results = ensemble_testing(TST_Features,trained_ensemble);

c1=0;
c2=0;
for i=1:size(test_results.predictions,1)
    if test_results.predictions(i) == 1
        c1 = c1+1;
    else
        c2 = c2+1;
    end
end
disp(['Total Frames ' num2str(size(test_results.predictions,1))]);
disp(['Forged       ' num2str(c1)]);
disp(['Non-Forged   ' num2str(c2)]);
if (c2>c1)
    app.ResultTextArea.Value='Video is Non-Forged';
else
    app.ResultTextArea.Value='Video is Forged';
end
%disp(test_results.predictions);
        end

        % Value changed function: ResultTextArea
        function ResultTextAreaValueChanged(app, event)
            value = app.ResultTextArea.Value;
            
        end

        % Value changed function: VideoEditField
        function VideoEditFieldValueChanged(app, event)
            value = app.VideoEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.VideoEditFieldLabel = uilabel(app.UIFigure);
            app.VideoEditFieldLabel.Text = 'Video';
            app.VideoEditField = uieditfield(app.UIFigure, 'numeric');
            app.VideoEditField.ValueChangedFcn = createCallbackFcn(app, @VideoEditFieldValueChanged, true);
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.ValueChangedFcn = createCallbackFcn(app, @ResultTextAreaValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end